classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LeftPanel                      matlab.ui.container.Panel
        RSDDenavitHartenburgLinksCalculatorLabel  matlab.ui.control.Label
        ArmPanel                       matlab.ui.container.Panel
        LinksSliderLabel               matlab.ui.control.Label
        LinksSlider                    matlab.ui.control.Slider
        AdjustinglinksresetstheparametersLabel  matlab.ui.control.Label
        DHTable                        matlab.ui.control.Table
        CalculateMatriciesButton       matlab.ui.control.Button
        CalculationModeButtonGroup     matlab.ui.container.ButtonGroup
        SymbolicButton                 matlab.ui.control.ToggleButton
        RealButton                     matlab.ui.control.ToggleButton
        ClearButton                    matlab.ui.control.Button
        DenavitHartenburgTableLabel    matlab.ui.control.Label
        ExporttoMATLABButton           matlab.ui.control.Button
        LeavezeroesblankLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ForwardKinematicsTab           matlab.ui.container.Tab
        OuputTextAreaLabel             matlab.ui.control.Label
        OuputTextArea                  matlab.ui.control.TextArea
        OutputTable                    matlab.ui.control.Table
        SimplifyLabel                  matlab.ui.control.Label
        SimplifySwitch                 matlab.ui.control.Switch
        InverseKinematicsTab           matlab.ui.container.Tab
        JacobianMatrixTab              matlab.ui.container.Tab
        SettingsTab                    matlab.ui.container.Tab
        CurrentlyActiveSymbolsLabel    matlab.ui.control.Label
        SymbolsTable                   matlab.ui.control.Table
        Panel                          matlab.ui.container.Panel
        RoundingPrecisionEditFieldLabel  matlab.ui.control.Label
        RoundingPrecisionEditField     matlab.ui.control.EditField
        AdjustableSettingsLabel        matlab.ui.control.Label
        NosanitycheckingincludedLabel  matlab.ui.control.Label
        AboutTab                       matlab.ui.container.Tab
        CalculatorAppforRobotSystemsDesignLabel  matlab.ui.control.Label
        AuthorJimmyTracjtracnekoxnetMITLicenseLabel  matlab.ui.control.Label
        NotesLabel                     matlab.ui.control.Label
        WheninputtingvaluesintotheDHTableLabel  matlab.ui.control.Label
        WheninputtingvaluesintotheDHTableLabel_2  matlab.ui.control.Label
        WheninputtingvaluesintotheDHTableLabel_3  matlab.ui.control.Label
        WheninputtingvaluesintotheDHTableLabel_4  matlab.ui.control.Label
    end

    
    properties (Access = private)
        calcModeSymbolic = true % true = Symbolic, false = real
        simplifyFwdMat = true; 
        realModePrecision = 2; % Precision of rounding in real mode
        debugMode = false;
        
        H01 = eye(4);
        H12 = eye(4);
        H23 = eye(4);
        H3T = eye(4);
        H02 = eye(4);
        H03 = eye(4);
        H0T = eye(4);
        
        w00 = 0; % rotational velocity ^0 w_0 = 0; same with v.
        v00 = 0; %  link 0 should always have w/v = 0/0.
    end
    
    methods (Access = private)
        
        function mat = rotMatZ(app,theta)
            mat = [ cos(theta) -sin(theta) 0 0; ...
                    sin(theta) cos(theta) 0 0 ; ...
                    0 0 1 0; ...
                    0 0 0 1];
        end
        
        function mat = rotMatY(app,theta)
            mat = [ cos(theta) 0 sin(theta) 0; ...
                    0 1 0 0; ...
                    -sin(theta) 0 cos(theta) 0 ; ...
                    0 0 0 1];
        end
        
        function mat = rotMatX(app,theta)
            mat = [1 0 0 0; ...
                    0 cos(theta) -sin(theta) 0; ...
                    0 sin(theta) cos(theta) 0 ; ...
                    0 0 0 1];
        end
        
        function mat = xyzMat(app,x,y,z)
            mat = [ 1 0 0 x; ...
                    0 1 0 y; ...
                    0 0 1 z; ...
                    0 0 0 1 ];
        end
        
        
        function mat = processDHTable(app, row)
            mat = eye(4);
            
            if (app.calcModeSymbolic)
                iden = str2sym('1');
                mat = mat*iden;
            end
            
            if (row <= app.LinksSlider.Value)
                
    
                % DH Table Cols: Alpha, a, theta, d, [type]
                % The shortcut is Rx(alpha) Dx(A) Rz(theta) Dz(di)
                
                
                % Alpha refers to rotation using an X rotation matrix
                %  IRL, this refers to the displacement between old z and
                %  new z.
                if (~isempty(app.DHTable.Data{row,1})) % if it is empty
                    inputValue = str2num(app.DHTable.Data{row,1});
                    if (~isempty(inputValue) && ~app.calcModeSymbolic )
                        %input is a number
                        %convert to deg
                        mat = mat * round(rotMatX(app,deg2rad(inputValue)));
                    else
                        %input is symbolic
                        matTheta = str2sym(app.DHTable.Data{row,1});
                        mat = mat * rotMatX(app,matTheta);
                    end
                    
                end
                
                sprintf("Current matrix row: %d", row)
                disp("after alpha manip:")
                disp(mat)
                
                % A is the displacement between the Z-axis along the X
                %  Hence it's an xyzMat along X
                if (~isempty(app.DHTable.Data{row,2})) % if it is empty
                    inputValue = str2num(app.DHTable.Data{row,2});
                    if (~isempty(inputValue) && ~app.calcModeSymbolic )
                        %input is a number
                        mat = mat * xyzMat(app, inputValue, 0, 0);
                    else
                        %input is symbolic
                        matDist = str2sym(app.DHTable.Data{row,2});
                        mat = mat * xyzMat(app, matDist, 0, 0);
                    end
                    
                end
                
                sprintf("Current matrix row: %d", row)
                disp("after A manip:")
                disp(mat)
                
                % Theta refers to the rotation between the X's
                %  Hence it's an rotation about Z
                if (~isempty(app.DHTable.Data{row,3})) % if it is empty
                    inputValue = str2num(app.DHTable.Data{row,3});
                    if (~isempty(inputValue) && ~app.calcModeSymbolic )
                        %input is a number
                        %convert to deg
                        mat = mat * round(rotMatZ(app,(deg2rad(inputValue))));
                    else
                        %input is symbolic
                        matTheta = str2sym(app.DHTable.Data{row,3});
                        mat = mat * rotMatZ(app,matTheta);
                    end
                end
                
                sprintf("Current matrix row: %d", row)
                disp("after theta manip:")
                disp(mat)
                
                % D is the displacement between the X axis, along Z.
                %  Hence it's an xyzMat along Z
                if (~isempty(app.DHTable.Data{row,4})) % if it is empty
                    inputValue = str2num(app.DHTable.Data{row,4});
                    if (~isempty(inputValue) && ~app.calcModeSymbolic )
                        %input is a number
                        mat = mat * xyzMat(app, 0, 0, inputValue);
                    else
                        %input is symbolic
                        matDist = str2sym(app.DHTable.Data{row,4});
                        mat = mat * xyzMat(app, 0, 0, matDist);
                    end
                    
                end
                
                sprintf("Current matrix row: %d", row)
                disp("after D manip:")
                disp(mat)
                
            end            
        end
        function UpdateDHTable(app)
            app.LinksSlider.Value = round(app.LinksSlider.Value);
            value = app.LinksSlider.Value;
            
            newData = {'' '' '' '' 'R' }; %blank data for each column
            app.DHTable.Data = {};
            for i=1:value
                app.DHTable.Data = [app.DHTable.Data; newData];
            end
            
            app.DHTable.RowName = ["1" "2" "3" "T"];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UpdateDHTable(app);
            
        end

        % Value changed function: LinksSlider
        function LinksSliderValueChanged(app, event)
            UpdateDHTable(app);            
        end

        % Button pushed function: CalculateMatriciesButton
        function CalculateMatriciesButtonPushed(app, event)
            app.OutputTable.Data={}; % Reset Output Table Data
            %syms t1 t2 t3 l1 l2 l3 d1 d2 d3 theta phi;


            % -------- Process Hs -------- %
            

            app.H01 = processDHTable(app,1);
            app.H12 = processDHTable(app,2);
            app.H23 = processDHTable(app,3);
            app.H3T = processDHTable(app,4);


            app.H02 = app.H01*app.H12;
            app.H03 = app.H01*app.H12*app.H23;
            app.H0T = app.H01*app.H12*app.H23*app.H3T;
            
            if (class(app.H01) == "sym" && ...
                class(app.H12) == "sym" && ...
                class(app.H23) == "sym"&& ...
                class(app.H3T) == "sym")
                if (app.simplifyFwdMat)
                    app.H0T = simplify(app.H0T);
                end  
            end
            
            if (class(app.H01) == "sym" && ...
                class(app.H12) == "sym" && ...
                class(app.H23) == "sym")
                if (app.simplifyFwdMat)
                    app.H03 = simplify(app.H03);
                end  
            end
            
            if (class(app.H01) == "sym" && ...
                class(app.H12) == "sym")
                if (app.simplifyFwdMat)
                    app.H02 = simplify(app.H02);
                end  
            end
            
            H01Table = array2table(app.H01);
            H01TableChar = {size(app.H01,1),size(app.H01,2)};
            
            H12Table = array2table(app.H12);
            H12TableChar = {size(app.H12,1),size(app.H12,2)};
            
            H23Table = array2table(app.H23);
            H23TableChar = {size(app.H23,1),size(app.H23,2)};
            
            H3TTable = array2table(app.H3T);
            H3TTableChar = {size(app.H3T,1),size(app.H3T,2)};
            
            H02Table = array2table(app.H02);
            H02TableChar = {size(app.H02,1),size(app.H02,2)};
            
            H03Table = array2table(app.H03);
            H03TableChar = {size(app.H03,1),size(app.H03,2)};
            
            H0TTable = array2table(app.H0T);
            H0TTableChar = {size(app.H0T,1),size(app.H0T,2)};
            
            
            for x = 1:size(app.H01,1)
                for y = 1:size(app.H01,2)
                    if (class(app.H01) == "sym")
                        H01TableChar{x,y} = sprintf('%s',H01Table{x,y});
                    else
                        H01TableChar{x,y} = num2str(H01Table{x,y});
                    end

                    
                end
            end
            
            for x = 1:size(app.H12,1)
                for y = 1:size(app.H12,2)
                    if (class(app.H12) == "sym")
                        H12TableChar{x,y} = sprintf('%s',H12Table{x,y});
                    else
                        H12TableChar{x,y} = num2str(H12Table{x,y});
                    end
                end
            end
            
            for x = 1:size(app.H23,1)
                for y = 1:size(app.H23,2)
                    if (class(app.H23) == "sym")
                        H23TableChar{x,y} = sprintf('%s',H23Table{x,y});
                    else
                        H23TableChar{x,y} = num2str(H23Table{x,y});
                    end
                end
            end
            
            for x = 1:size(app.H3T,1)
                for y = 1:size(app.H3T,2)
                    if (class(app.H3T) == "sym")
                        H3TTableChar{x,y} = sprintf('%s',H3TTable{x,y});
                    else
                        H3TTableChar{x,y} = num2str(H3TTable{x,y});
                    end
                end
            end
            
            for x = 1:size(app.H02,1)
                for y = 1:size(app.H02,2)
                    if (class(app.H02) == "sym")
                        H02TableChar{x,y} = sprintf('%s',H02Table{x,y});
                    else
                        H02TableChar{x,y} = num2str(H02Table{x,y});
                    end
                end
            end
            
            for x = 1:size(app.H03,1)
                for y = 1:size(app.H03,2)
                    if (class(app.H03) == "sym")
                        H03TableChar{x,y} = sprintf('%s',H03Table{x,y});
                    else
                        H03TableChar{x,y} = num2str(H03Table{x,y});
                    end
                end
            end
            
            for x = 1:size(app.H0T,1)
                for y = 1:size(app.H0T,2)
                    if (class(app.H0T) == "sym")
                        H0TTableChar{x,y} = sprintf('%s',H0TTable{x,y});
                    else
                        H0TTableChar{x,y} = num2str(H0TTable{x,y});
                    end
                end
            end
            
            H01Label = {'H01' '' '' ''};
            H12Label = {'H12' '' '' ''};
            H23Label = {'H23' '' '' ''};
            H3TLabel = {'H3T' '' '' ''};
            H02Label = {'H02' '' '' ''};
            H03Label = {'H03' '' '' ''};
            H0TLabel = {'H0T' '' '' ''};
            
            switch app.LinksSlider.Value
                
                case 1
                    app.OutputTable.Data = [H01Label; H01TableChar];
                case 2
                    app.OutputTable.Data = [H01Label; H01TableChar; ...
                                            H12Label; H12TableChar;
                                            H02Label; H02TableChar];
                case 3
                    app.OutputTable.Data = [H01Label; H01TableChar; ...
                                            H12Label; H12TableChar;
                                            H23Label; H23TableChar;
                                            H02Label; H02TableChar;
                                            H03Label; H03TableChar];       
                case 4
                    app.OutputTable.Data = [H01Label; H01TableChar; ...
                                            H12Label; H12TableChar;
                                            H23Label; H23TableChar;
                                            H3TLabel; H3TTableChar;
                                            H02Label; H02TableChar;
                                            H03Label; H03TableChar;
                                            H0TLabel; H0TTableChar];
            end
            
            
            addStyle(app.OutputTable, uistyle('BackgroundColor', '#DDD'),'row', [1:5:40]);
            
            app.SymbolsTable.Data = syms;
            
            %A = rotMatZ(app,theta);
            %app.OutputTable.Data=array2table(A);


            %dataTable = array2table(A);
            
            %app.OuputTextArea.Value = char(app.OutputTable.Data{2,2});
            %app.OuputTextArea.Value = [app.OuputTextArea.Value; "new line"];
            
            %app.OutputTable.Data{2,2} = char(app.OutputTable.Data{2,2});
            
            %app.OutputTable.Data = dataTest{1,1};
            
            %for x = 1:size(A,1)
            %    for y = 1:size(A,2)
            %        app.OutputTable.Data{x,y} = char(dataTable{x,y});
            %    end
            %end


        end

        % Selection changed function: CalculationModeButtonGroup
        function CalculationModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationModeButtonGroup.SelectedObject;
            app.calcModeSymbolic = selectedButton == app.SymbolicButton;
            app.OuputTextArea.Value = sprintf('%d',app.calcModeSymbolic);
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            UpdateDHTable(app);
        end

        % Value changed function: SimplifySwitch
        function SimplifySwitchValueChanged(app, event)
            value = app.SimplifySwitch.Value;
            app.simplifyFwdMat = "On" == value;
            app.OuputTextArea.Value = sprintf('%d',app.simplifyFwdMat);
        end

        % Value changed function: RoundingPrecisionEditField
        function RoundingPrecisionEditFieldValueChanged(app, event)
            value = app.RoundingPrecisionEditField.Value;
            app.realModePrecision = round(str2double(value));
        end

        % Callback function
        function DEBUGBUTTONButtonPushed(app, event)
            sprintf("H01 -- Class %s",class(app.H01))
            disp(app.H01)
            sprintf("H12 -- Class %s",class(app.H12))
            disp(app.H12)
        end

        % Callback function
        function DebugModeSwitchValueChanged(app, event)
            value = app.DebugModeSwitch.Value;
            app.DebugModeSwitch = "On" == value;
        end

        % Button pushed function: ExporttoMATLABButton
        function ExporttoMATLABButtonPushed(app, event)
            % Exports all matricies to workspace 
            assignin("base","H01",app.H01);
            assignin("base","H12",app.H12);
            assignin("base","H23",app.H23);
            assignin("base","H3T",app.H3T);
            assignin("base","H02",app.H02);
            assignin("base","H03",app.H03);
            assignin("base","H0T",app.H0T);
            assignin("base","DHTable",app.DHTable.Data);
            disp("The matricies have been exported to the MATLAB workspace.")
            
        end

        % Cell edit callback: DHTable
        function DHTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            for i = 1:size(app.DHTable.Data,1)
                if (app.DHTable.Data{i,5} ~= 'P')
                    app.DHTable.Data{i,5} = 'R';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.RSDDenavitHartenburgLinksCalculatorLabel = uilabel(app.LeftPanel);
            app.RSDDenavitHartenburgLinksCalculatorLabel.Text = 'RSD Denavit-Hartenburg Links Calculator';
            app.ArmPanel = uipanel(app.LeftPanel);
            app.ArmPanel.Title = 'Arm Parameters';
            app.LinksSliderLabel = uilabel(app.ArmPanel);
            app.LinksSliderLabel.Text = 'Links';
            app.LinksSlider = uislider(app.ArmPanel);
            app.LinksSlider.Limits = [1 4];
            app.LinksSlider.MajorTicks = [1 2 3 4];
            app.LinksSlider.MajorTickLabels = {'1', '2', '3', 'T'};
            app.LinksSlider.ValueChangedFcn = createCallbackFcn(app, @LinksSliderValueChanged, true);
            app.LinksSlider.MinorTicks = [];
            app.LinksSlider.Value = 3;
            app.AdjustinglinksresetstheparametersLabel = uilabel(app.ArmPanel);
            app.AdjustinglinksresetstheparametersLabel.Text = 'Adjusting links resets the parameters!';
            app.DHTable = uitable(app.LeftPanel);
            app.DHTable.ColumnName = {'alpha'; 'a'; 'theta'; 'd'; 'Type'};
            app.DHTable.RowName = {};
            app.DHTable.ColumnEditable = [true true true true true false];
            app.DHTable.CellEditCallback = createCallbackFcn(app, @DHTableCellEdit, true);
            app.CalculateMatriciesButton = uibutton(app.LeftPanel, 'push');
            app.CalculateMatriciesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMatriciesButtonPushed, true);
            app.CalculateMatriciesButton.Text = 'Calculate Matricies';
            app.CalculationModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.CalculationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationModeButtonGroupSelectionChanged, true);
            app.CalculationModeButtonGroup.Title = 'Calculation Mode';
            app.SymbolicButton = uitogglebutton(app.CalculationModeButtonGroup);
            app.SymbolicButton.Text = 'Symbolic';
            app.SymbolicButton.Value = true;
            app.RealButton = uitogglebutton(app.CalculationModeButtonGroup);
            app.RealButton.Text = 'Real';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DenavitHartenburgTableLabel = uilabel(app.LeftPanel);
            app.DenavitHartenburgTableLabel.Text = 'Denavit-Hartenburg Table';
            app.ExporttoMATLABButton = uibutton(app.LeftPanel, 'push');
            app.ExporttoMATLABButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoMATLABButtonPushed, true);
            app.ExporttoMATLABButton.Text = 'Export to MATLAB';
            app.LeavezeroesblankLabel = uilabel(app.LeftPanel);
            app.LeavezeroesblankLabel.Text = 'Leave zeroes blank';
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ForwardKinematicsTab = uitab(app.TabGroup);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.OuputTextAreaLabel = uilabel(app.ForwardKinematicsTab);
            app.OuputTextAreaLabel.Text = 'Ouput';
            app.OuputTextArea = uitextarea(app.ForwardKinematicsTab);
            app.OutputTable = uitable(app.ForwardKinematicsTab);
            app.OutputTable.ColumnName = {''};
            app.OutputTable.RowName = {};
            app.SimplifyLabel = uilabel(app.ForwardKinematicsTab);
            app.SimplifyLabel.Text = 'Simplify:';
            app.SimplifySwitch = uiswitch(app.ForwardKinematicsTab, 'slider');
            app.SimplifySwitch.ValueChangedFcn = createCallbackFcn(app, @SimplifySwitchValueChanged, true);
            app.SimplifySwitch.Value = 'On';
            app.InverseKinematicsTab = uitab(app.TabGroup);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.JacobianMatrixTab = uitab(app.TabGroup);
            app.JacobianMatrixTab.Title = 'Jacobian Matrix';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.CurrentlyActiveSymbolsLabel = uilabel(app.SettingsTab);
            app.CurrentlyActiveSymbolsLabel.Text = 'Currently Active Symbols';
            app.SymbolsTable = uitable(app.SettingsTab);
            app.SymbolsTable.ColumnName = {''};
            app.SymbolsTable.RowName = {};
            app.Panel = uipanel(app.SettingsTab);
            app.RoundingPrecisionEditFieldLabel = uilabel(app.Panel);
            app.RoundingPrecisionEditFieldLabel.Text = 'Rounding Precision';
            app.RoundingPrecisionEditField = uieditfield(app.Panel, 'text');
            app.RoundingPrecisionEditField.ValueChangedFcn = createCallbackFcn(app, @RoundingPrecisionEditFieldValueChanged, true);
            app.RoundingPrecisionEditField.Value = '2';
            app.AdjustableSettingsLabel = uilabel(app.Panel);
            app.AdjustableSettingsLabel.Text = 'Adjustable Settings';
            app.NosanitycheckingincludedLabel = uilabel(app.Panel);
            app.NosanitycheckingincludedLabel.Text = 'No sanity checking included!';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.CalculatorAppforRobotSystemsDesignLabel = uilabel(app.AboutTab);
            app.CalculatorAppforRobotSystemsDesignLabel.Text = 'Calculator App for Robot Systems Design';
            app.AuthorJimmyTracjtracnekoxnetMITLicenseLabel = uilabel(app.AboutTab);
            app.AuthorJimmyTracjtracnekoxnetMITLicenseLabel.Text = {'Author:Jimmy Trac (jtrac@nekox.net) '; 'MIT License'; ''; 'Special thanks:'; 'Michelle Dunn for teaching us and being awesome;'; ''; 'The guinea pigs:'; 'Bi Wan Low, Michael Dobbe...'; ''; 'For Robot Systems Design.'; 'Plenty of love from Phil''s friends to compensate for Phil''s antics.'; 'and possibly for the fact that half of us barely showed up to any more than half the classes.'};
            app.NotesLabel = uilabel(app.AboutTab);
            app.NotesLabel.Text = 'Notes';
            app.WheninputtingvaluesintotheDHTableLabel = uilabel(app.AboutTab);
            app.WheninputtingvaluesintotheDHTableLabel.Text = {'When inputting values into the DH Table, empty values should be left as blank, not zero.'; 'The DH table is a table of strings, with each nonempty cell converted into a symbol for use. '; 'That means if you put in zero, the ''0'' character will become a symbol'};
            app.WheninputtingvaluesintotheDHTableLabel_2 = uilabel(app.AboutTab);
            app.WheninputtingvaluesintotheDHTableLabel_2.Text = {'Calculation Mode:'; '    - Symbolic: Nothing is evaluated, Sin(90) stays as Sin(9)'; '    - Real: Evaluation enabled, Sin(90) becomes 1.'};
            app.WheninputtingvaluesintotheDHTableLabel_3 = uilabel(app.AboutTab);
            app.WheninputtingvaluesintotheDHTableLabel_3.Text = {'Simplify:'; '    - Enabled: Simplifies trig identities together, such as Cos(t1+t2)'; '    - Disableld: Leaves everything expanded, mouse over or export to see full matrix cells.'};
            app.WheninputtingvaluesintotheDHTableLabel_4 = uilabel(app.AboutTab);
            app.WheninputtingvaluesintotheDHTableLabel_4.Text = {'Output weird? Don''t worry! Export to MATLAB, this program uses a number of '; 'hacks to display the internal matricies into tables.'; 'The internal matrix maths is quite "epic" as some would say.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end